classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GainSlider               matlab.ui.control.Slider
        GainSliderLabel          matlab.ui.control.Label
        SelectfilestostartLabel  matlab.ui.control.Label
        VisibilitySlider         matlab.ui.control.Slider
        VisibilitySliderLabel    matlab.ui.control.Label
        ExportAllButton          matlab.ui.control.Button
        SelectFilesButton        matlab.ui.control.Button
        ExportCurrentButton      matlab.ui.control.Button
        SeeDetailsButton         matlab.ui.control.Button
        NextButton               matlab.ui.control.Button
        PreviousButton           matlab.ui.control.Button
        ThresholdSlider          matlab.ui.control.Slider
        ThresholdSliderLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        i % iterate file
        fileList % list of files selected
        currentFile
        threshold
        visibility
        depth
        gain
        images
        plots
        pause
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("funcs\")
            addpath("C:\Users\Deniz\Desktop\fl_us_new")
            app.i = 1;
            set(findobj(app.UIFigure,'Type','uibutton'), 'Enable','off')
            set(app.SelectFilesButton,'Enable','on')
            app.threshold = 100;
            app.ThresholdSlider.Value = app.threshold;
            app.visibility = 50;
            app.VisibilitySlider.Value = app.visibility;
            app.gain = 10;
            app.GainSlider.Value = app.gain;
            app.pause = true;
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            [app.fileList] = selectFiles();
            app.pause = false;

            app.images = num2cell(zeros(1, numel(app.fileList)));
            app.plots =  num2cell(zeros(1, numel(app.fileList)));

            for file = 1:numel(app.fileList)
                    app.SelectfilestostartLabel.Text = "Currently processing: " + app.fileList(file) + " (file " + file + " of " + numel(app.fileList) + ")";
                    [coregImage,dataPlots] = imageCoregistration(app.fileList(file), app.threshold, app.visibility, app.gain);
                    app.images(file) = {coregImage};
                    app.plots(file) = {dataPlots};
            end

            app.currentFile = 1;
            app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
            set(findobj(app.UIFigure,'Type','uibutton'), 'Enable','on')

            imagesc(app.UIAxes, app.images{app.currentFile})
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            cla(app.UIAxes)
            if app.currentFile < numel(app.fileList)
                app.currentFile = app.currentFile + 1;
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
            else
                app.currentFile = 1;
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
            end

            imagesc(app.UIAxes, app.images{app.currentFile})
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            cla(app.UIAxes)
            if app.currentFile > 1
                app.currentFile = app.currentFile - 1;
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
            else
                app.currentFile = numel(app.fileList);
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
            end

            imagesc(app.UIAxes, app.images{app.currentFile})
        end

        % Value changing function: ThresholdSlider
        function ThresholdSliderValueChanging(app, event)
            if ~app.pause
                app.pause = true;
                app.SelectfilestostartLabel.Text = "Adjusting threshold for " + app.fileList(app.currentFile) + " ...";
                app.threshold = floor(event.Value);
                
                [coregImage,dataPlots] = imageCoregistration(app.fileList(app.currentFile), app.threshold, app.visibility, app.gain);
                app.images(app.currentFile) = {coregImage};
                app.plots(app.currentFile) = {dataPlots};
    
                imagesc(app.UIAxes, app.images{app.currentFile})
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
                app.pause = false;
            end
        end

        % Value changed function: VisibilitySlider
        function VisibilitySliderValueChanged(app, event)
            if ~app.pause
                app.pause = true;
                app.SelectfilestostartLabel.Text = "Adjusting visibility for " + app.fileList(app.currentFile) + " ...";
                app.visibility = floor(event.Value);
                
                [coregImage,dataPlots] = imageCoregistration(app.fileList(app.currentFile), app.threshold, app.visibility, app.gain);
                app.images(app.currentFile) = {coregImage};
                app.plots(app.currentFile) = {dataPlots};
    
                imagesc(app.UIAxes, app.images{app.currentFile})
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
                app.pause = false;
            end
        end

        % Button pushed function: SeeDetailsButton
        function SeeDetailsButtonPushed(app, event)
            figure('Position', [50 50 1000 750])
            imagesc(app.plots{app.currentFile})
            axis off
        end

        % Value changed function: GainSlider
        function GainSliderValueChanged(app, event)
            if ~app.pause
                app.pause = true;
                app.SelectfilestostartLabel.Text = "Adjusting gain for " + app.fileList(app.currentFile) + " ...";
                app.gain = floor(event.Value);
                
                [coregImage,dataPlots] = imageCoregistration(app.fileList(app.currentFile), app.threshold, app.visibility, app.gain);
                app.images(app.currentFile) = {coregImage};
                app.plots(app.currentFile) = {dataPlots};
    
                imagesc(app.UIAxes, app.images{app.currentFile})
                app.SelectfilestostartLabel.Text = app.fileList(app.currentFile) + " (file " + app.currentFile + " of " + numel(app.fileList) + ")";
                app.pause = false;
            end
        end

        % Button pushed function: ExportCurrentButton
        function ExportCurrentButtonPushed(app, event)
            app.SelectfilestostartLabel.Text = "Saving current image...";
            if ~exist("processed_images", "dir")
                mkdir("processed_images");
            end

            filename = fullfile("processed_images", app.fileList(app.currentFile) + "_data_t" + app.threshold + "v" + app.visibility + "g" + app.gain + ".png");
            fig = figure('Position', [50 50 1000 750], 'visible','off');
            imagesc(app.images{app.currentFile})
            axis off
            exportgraphics(fig, filename, "Resolution", 300)
            close;

            fig = figure('Position', [50 50 1000 750], 'visible','off');
            imagesc(app.plots{app.currentFile})
            axis off
            filename = fullfile("processed_images", app.fileList(app.currentFile) + "_coreg_t" + app.threshold + "v" + app.visibility + app.visibility + "g" + app.gain + ".png");
            exportgraphics(fig, filename, "Resolution", 300)
            app.SelectfilestostartLabel.Text = "Image saved";
        end

        % Button pushed function: ExportAllButton
        function ExportAllButtonPushed(app, event)
            app.SelectfilestostartLabel.Text = "Saving all images...";
            if ~exist("processed_images", "dir")
                mkdir("processed_images");
            end

            for file = 1:numel(app.fileList)
                filename = fullfile("processed_images", app.fileList(file) + "_data_t" + app.threshold + "v" + app.visibility + "g" + app.gain + ".png");
                fig = figure('Position', [50 50 1000 750], 'visible','off');
                imagesc(app.images{file})
                axis off
                exportgraphics(fig, filename, "Resolution", 300)
                close;
    
                fig = figure('Position', [50 50 1000 750], 'visible','off');
                imagesc(app.plots{file})
                axis off
                filename = fullfile("processed_images", app.fileList(file) + "_coreg_t" + app.threshold + "v" + app.visibility + app.visibility + "g" + app.gain + ".png");
                exportgraphics(fig, filename, "Resolution", 300)
            end

            app.SelectfilestostartLabel.Text = "Images saved";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Depth-corrected FL data over US image')
            xlabel(app.UIAxes, 'Distance (mm)')
            ylabel(app.UIAxes, 'Depth (mm)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.UIAxes.YTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 150];
            app.ThresholdSlider.MajorTicks = [0 25 50 75 100 125 150];
            app.ThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdSliderValueChanging, true);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.SeeDetailsButton = uibutton(app.UIFigure, 'push');
            app.SeeDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @SeeDetailsButtonPushed, true);
            app.SeeDetailsButton.Text = 'See Details';
            app.ExportCurrentButton = uibutton(app.UIFigure, 'push');
            app.ExportCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCurrentButtonPushed, true);
            app.ExportCurrentButton.Text = 'Export Current';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.ExportAllButton = uibutton(app.UIFigure, 'push');
            app.ExportAllButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAllButtonPushed, true);
            app.ExportAllButton.Text = 'Export All';
            app.VisibilitySliderLabel = uilabel(app.UIFigure);
            app.VisibilitySliderLabel.Text = 'Visibility';
            app.VisibilitySlider = uislider(app.UIFigure);
            app.VisibilitySlider.Limits = [0 150];
            app.VisibilitySlider.MajorTicks = [0 25 50 75 100 125 150];
            app.VisibilitySlider.ValueChangedFcn = createCallbackFcn(app, @VisibilitySliderValueChanged, true);
            app.SelectfilestostartLabel = uilabel(app.UIFigure);
            app.SelectfilestostartLabel.Text = 'Select files to start';
            app.GainSliderLabel = uilabel(app.UIFigure);
            app.GainSliderLabel.Text = {'Gain'; ''};
            app.GainSlider = uislider(app.UIFigure);
            app.GainSlider.Limits = [0 20];
            app.GainSlider.MajorTicks = [0 5 10 15 20];
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @GainSliderValueChanged, true);
            app.GainSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end